classdef ListeningTestGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Signal1Button          matlab.ui.control.Button
        Signal2Button          matlab.ui.control.Button
        Signal3Button          matlab.ui.control.Button
        NextButton             matlab.ui.control.Button
        ButtonGroup_Direction  matlab.ui.container.ButtonGroup
        Button_0deg            matlab.ui.control.RadioButton
        Button_neg45deg        matlab.ui.control.RadioButton
        Button_45deg           matlab.ui.control.RadioButton
        Button_90deg           matlab.ui.control.RadioButton
        Button_neg90deg        matlab.ui.control.RadioButton
        Button_neg75deg        matlab.ui.control.RadioButton
        Button_75deg           matlab.ui.control.RadioButton
        Button_neg60deg        matlab.ui.control.RadioButton
        Button_60deg           matlab.ui.control.RadioButton
        Button_neg30deg        matlab.ui.control.RadioButton
        Button_30deg           matlab.ui.control.RadioButton
        Button_15deg           matlab.ui.control.RadioButton
        Image                  matlab.ui.control.Image
        Button_neg15deg        matlab.ui.control.RadioButton
        Button_82_5deg         matlab.ui.control.RadioButton
        Button_neg82_5deg      matlab.ui.control.RadioButton
        Button_67_5deg         matlab.ui.control.RadioButton
        Button_neg_67_5deg     matlab.ui.control.RadioButton
        Button_52_5deg         matlab.ui.control.RadioButton
        Button_neg_52_5deg     matlab.ui.control.RadioButton
        Button_37_5deg         matlab.ui.control.RadioButton
        Button_neg_37_5deg     matlab.ui.control.RadioButton
        Button_22_5deg         matlab.ui.control.RadioButton
        Button_neg_22_5deg     matlab.ui.control.RadioButton
        Button_7_5deg          matlab.ui.control.RadioButton
        Button_neg_7_5deg      matlab.ui.control.RadioButton
        WheredoyouhearthesignalfromLabel  matlab.ui.control.Label
        PageLabel              matlab.ui.control.Label
        Signal4Button          matlab.ui.control.Button
        Signal5Button          matlab.ui.control.Button
    end

    
    properties (Access = public)
        maxMethods = 5;
        maxScenes = 1;
        maxSignals = 4;
        maxRepetitions = 2;
        maxPages = 1;
        
        TestValues; %this is a cell that holds all the results of the experiment
   
        randSignal; % contains random order of Signals
        randScene; % contains random order of Scene
        randMethod; % contains random order of Method
        
        Scene;
        Signal;
        Method;
        signalPath;
        Repetition = 1;
        
        sceneIter = 1;
        signalIter = 1;
        
        
        signalPathSc1 = {'Sound_Sc1_Unprocessed_Target.wav',...
            'Sound_Sc1_m1Target.wav',...
            'Sound_Sc1_m2Target.wav',...
            'Sound_Sc1_m3Target.wav',...
            'Sound_Sc1_m3Target.wav';...
            'Sound_Sc1_Unprocessed_r1.wav',...
            'Sound_Sc1_m1_r1.wav',...
            'Sound_Sc1_m2_r1.wav',...
            'Sound_Sc1_m3_r1.wav',...
            'Sound_Sc1_m4_r1.wav';...
            'Sound_Sc1_Unprocessed_r2.wav',...
            'Sound_Sc1_m1_r2.wav',...
            'Sound_Sc1_m2_r2.wav',...
            'Sound_Sc1_m3_r2.wav',...
            'Sound_Sc1_m4_r2.wav';...
            'Sound_Sc1_Unprocessed_r3.wav',...
            'Sound_Sc1_m1_r3.wav',...
            'Sound_Sc1_m2_r3.wav',...
            'Sound_Sc1_m3_r3.wav',...
            'Sound_Sc1_m4_r3.wav'};
        
        signalPathSc2 = {'Sound_Sc2_Unprocessed_Target.wav',...
            'Sound_Sc2_m1Target.wav',...
            'Sound_Sc2_m2Target.wav',...
            'Sound_Sc2_m3Target.wav',...
            'Sound_Sc2_m3Target.wav';...
            'Sound_Sc2_Unprocessed_r1.wav',...
            'Sound_Sc2_m1_r1.wav',...
            'Sound_Sc2_m2_r1.wav',...
            'Sound_Sc2_m3_r1.wav',...
            'Sound_Sc2_m4_r1.wav';...
            'Sound_Sc2_Unprocessed_r2.wav',...
            'Sound_Sc2_m1_r2.wav',...
            'Sound_Sc2_m2_r2.wav',...
            'Sound_Sc2_m3_r2.wav',...
            'Sound_Sc2_m4_r2.wav';...
            'Sound_Sc2_Unprocessed_r3.wav',...
            'Sound_Sc2_m1_r3.wav',...
            'Sound_Sc2_m2_r3.wav',...
            'Sound_Sc2_m3_r3.wav',...
            'Sound_Sc2_m4_r3.wav'};
        
        signalPathSc3 = {'Sound_Sc3_Unprocessed_Target.wav',...
            'Sound_Sc3_m1Target.wav',...
            'Sound_Sc3_m2Target.wav',...
            'Sound_Sc3_m3Target.wav',...
            'Sound_Sc3_m3Target.wav';...
            'Sound_Sc3_Unprocessed_r1.wav',...
            'Sound_Sc3_m1_r1.wav',...
            'Sound_Sc3_m2_r1.wav',...
            'Sound_Sc3_m3_r1.wav',...
            'Sound_Sc3_m4_r1.wav';...
            'Sound_Sc3_Unprocessed_r2.wav',...
            'Sound_Sc3_m1_r2.wav',...
            'Sound_Sc3_m2_r2.wav',...
            'Sound_Sc3_m3_r2.wav',...
            'Sound_Sc3_m4_r2.wav';...
            'Sound_Sc3_Unprocessed_r3.wav',...
            'Sound_Sc3_m1_r3.wav',...
            'Sound_Sc3_m2_r3.wav',...
            'Sound_Sc3_m3_r3.wav',...
            'Sound_Sc3_m4_r3.wav'};
          PageCount = 1;
        
    end
       

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TestValues = zeros(app.maxSignals,app.maxMethods,app.maxScenes); %this is a cell that holds all the values of the experiment
            app.signalPath = cat(3, app.signalPathSc1,app.signalPathSc2, app.signalPathSc3);
            
            app.randSignal = randperm(app.maxSignals);
            app.randScene = randperm(app.maxScenes);
            app.randMethod = randperm(app.maxMethods);
            app.Scene = app.randScene(app.sceneIter);
            app.Signal = app.randSignal(app.signalIter);
            app.maxPages = app.maxScenes*app.maxSignals*app.maxRepetitions;
            
            app.PageLabel.Text = ['Page ' num2str(app.PageCount) '/' num2str(app.maxPages)];
        end

        % Selection changed function: ButtonGroup_Direction
        function ButtonGroup_DirectionSelectionChanged(app, event)
            if app.Button_0deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = 0;
                elseif app.Button_neg_7_5deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = -7.5;
            elseif app.Button_7_5deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = 7.5;
            elseif app.Button_neg_22_5deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = -22.5;
            elseif app.Button_22_5deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = 22.5;
            elseif app.Button_neg_37_5deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = -37.5;
            elseif app.Button_37_5deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = 37.5;
            elseif app.Button_neg_52_5deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = -52.5;
            elseif app.Button_52_5deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = 52.5;
            elseif app.Button_neg_67_5deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = -67.5;
            elseif app.Button_67_5deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = 67.5;
            elseif app.Button_neg82_5deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = -82.5;
            elseif app.Button_82_5deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = 82.5;
            elseif app.Button_15deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = 15;
            elseif app.Button_neg15deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = -15;
            elseif app.Button_30deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = 30;
            elseif app.Button_neg30deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = -30;                
            elseif app.Button_45deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = 45;
            elseif app.Button_neg45deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = -45;
            elseif app.Button_60deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = 60;
            elseif app.Button_neg60deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = -60;
            elseif app.Button_75deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = 75;
            elseif app.Button_neg75deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = -75;
            elseif app.Button_90deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = 90;
            elseif app.Button_neg90deg.Value
                app.TestValues(app.Signal, app.Method, app.Scene) = -90;
            else
                app.TestValues(app.Signal, app.Method, app.Scene) = 0;
            end
                    
        end

        % Button pushed function: Signal1Button
        function Signal1ButtonPushed(app, event)
            clear sound;
            app.Button_0deg.Value = true;
            app.Method = app.randMethod(1);
            [y, fs] = audioread(app.signalPath{app.Signal,app.Method,app.Scene});
            sound(y,fs);
        end

        % Button pushed function: Signal2Button
        function Signal2ButtonPushed(app, event)
            clear sound;
            app.Button_0deg.Value = true;
            app.Method = app.randMethod(2);
            [y, fs] = audioread(app.signalPath{app.Signal,app.Method,app.Scene});
            sound(y,fs);
        end

        % Button pushed function: Signal3Button
        function Signal3ButtonPushed(app, event)
            clear sound;
            app.Button_0deg.Value = true;
            app.Method = app.randMethod(3);
            [y, fs] = audioread(app.signalPath{app.Signal,app.Method,app.Scene});
            sound(y,fs);
        end

        % Button pushed function: Signal4Button
        function Signal4ButtonPushed(app, event)
            clear sound;
            app.Button_0deg.Value = true;
            app.Method = app.randMethod(4);
            [y, fs] = audioread(app.signalPath{app.Signal,app.Method,app.Scene});
            sound(y,fs);
        end

        % Button pushed function: Signal5Button
        function Signal5ButtonPushed(app, event)
            clear sound;
            app.Button_0deg.Value = true;
            app.Method = app.randMethod(5);
            [y, fs] = audioread(app.signalPath{app.Signal,app.Method,app.Scene});
            sound(y,fs);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            clear sound;
            app.PageCount = app.PageCount + 1;
            app.PageLabel.Text = ['Page ' num2str(app.PageCount) '/' num2str(app.maxPages)];
            app.signalIter = app.signalIter + 1;
            if (app.signalIter < app.maxSignals + 1)
                app.Button_0deg.Value = true;
                app.Signal = app.randSignal(app.signalIter);
                app.randMethod = randperm(app.maxMethods);
                if ((app.sceneIter == app.maxScenes) && (app.signalIter == app.maxSignals) &&(app.Repetition == app.maxRepetitions))
                    app.NextButton.Text = 'Finish';
                end
            else
                app.signalIter = 1;
                app.sceneIter = app.sceneIter + 1;
                if (app.sceneIter < app.maxScenes + 1)
                    app.Scene = app.randScene(app.sceneIter);
                    app.randSignal = randperm(app.maxSignals);
                    app.randMethod = randperm(app.maxMethods);
                    
                    app.Signal = app.randSignal(app.signalIter);
                else
                    app.Repetition = app.Repetition + 1;
                    if(app.Repetition < app.maxRepetitions + 1)
                        app.signalIter = 1;
                        app.sceneIter = 1;
                        
                        app.randScene = randperm(app.maxScenes);
                        app.randSignal = randperm(app.maxSignals);
                        app.randMethod = randperm(app.maxMethods);
                        app.Scene = app.randScene(app.sceneIter);
                        app.Signal = app.randSignal(app.signalIter);
                        
                    else
                        filename = 'Result.xlsx';
                        writematrix(app.TestValues(:,:,1),filename,"Sheet",1,"Range",'C3:G6');
%                         writematrix(app.TestValues(:,:,2),filename,"Sheet",1,"Range",'C9:G12');
%                         writematrix(app.TestValues(:,:,2),filename,"Sheet",1,"Range",'C15:G18');
                        
                        delete(app)
                    end    
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
                    filename = 'Result.xlsx';
                    writematrix(app.TestValues(:,:,1),filename,"Sheet",1,"Range",'C3:G6');
%                     writematrix(app.TestValues(:,:,2),filename,"Sheet",1,"Range",'C9:G12');
%                     writematrix(app.TestValues(:,:,2),filename,"Sheet",1,"Range",'C15:G18');

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Signal1Button = uibutton(app.UIFigure, 'push');
            app.Signal1Button.ButtonPushedFcn = createCallbackFcn(app, @Signal1ButtonPushed, true);
            app.Signal1Button.Text = 'Signal 1';
            app.Signal2Button = uibutton(app.UIFigure, 'push');
            app.Signal2Button.ButtonPushedFcn = createCallbackFcn(app, @Signal2ButtonPushed, true);
            app.Signal2Button.Text = 'Signal 2';
            app.Signal3Button = uibutton(app.UIFigure, 'push');
            app.Signal3Button.ButtonPushedFcn = createCallbackFcn(app, @Signal3ButtonPushed, true);
            app.Signal3Button.Text = 'Signal 3';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next >';
            app.ButtonGroup_Direction = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Direction.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_DirectionSelectionChanged, true);
            app.Button_0deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_0deg.Text = '';
            app.Button_0deg.Value = true;
            app.Button_neg45deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_neg45deg.Text = '';
            app.Button_45deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_45deg.Text = '';
            app.Button_90deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_90deg.Text = '';
            app.Button_neg90deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_neg90deg.Text = '';
            app.Button_neg75deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_neg75deg.Text = '';
            app.Button_75deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_75deg.Text = '';
            app.Button_neg60deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_neg60deg.Text = '';
            app.Button_60deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_60deg.Text = '';
            app.Button_neg30deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_neg30deg.Text = '';
            app.Button_30deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_30deg.Text = '';
            app.Button_15deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_15deg.Text = '';
            app.Image = uiimage(app.ButtonGroup_Direction);
            app.Image.ImageSource = 'LoctationImage.PNG';
            app.Button_neg15deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_neg15deg.Text = '';
            app.Button_82_5deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_82_5deg.Text = '';
            app.Button_neg82_5deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_neg82_5deg.Text = '';
            app.Button_67_5deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_67_5deg.Text = '';
            app.Button_neg_67_5deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_neg_67_5deg.Text = '';
            app.Button_52_5deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_52_5deg.Text = '';
            app.Button_neg_52_5deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_neg_52_5deg.Text = '';
            app.Button_37_5deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_37_5deg.Text = '';
            app.Button_neg_37_5deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_neg_37_5deg.Text = '';
            app.Button_22_5deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_22_5deg.Text = '';
            app.Button_neg_22_5deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_neg_22_5deg.Text = '';
            app.Button_7_5deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_7_5deg.Text = '';
            app.Button_neg_7_5deg = uiradiobutton(app.ButtonGroup_Direction);
            app.Button_neg_7_5deg.Text = '';
            app.WheredoyouhearthesignalfromLabel = uilabel(app.UIFigure);
            app.WheredoyouhearthesignalfromLabel.Text = 'Where do you hear the signal from ?';
            app.PageLabel = uilabel(app.UIFigure);
            app.PageLabel.Text = '';
            app.Signal4Button = uibutton(app.UIFigure, 'push');
            app.Signal4Button.ButtonPushedFcn = createCallbackFcn(app, @Signal4ButtonPushed, true);
            app.Signal4Button.Text = 'Signal 4';
            app.Signal5Button = uibutton(app.UIFigure, 'push');
            app.Signal5Button.ButtonPushedFcn = createCallbackFcn(app, @Signal5ButtonPushed, true);
            app.Signal5Button.Text = 'Signal 5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ListeningTestGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end